classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        SelectImageButton  matlab.ui.control.Button
        DetectButton       matlab.ui.control.Button
        ImageLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        img
        net
        faceDetector
    end
    
    methods (Access = private)
        
        function [J,bbox] = cropface(app, img)
            [bbox, ~, ~] = app.faceDetector.detect(img);
            if ~isempty(bbox)
                for i = 1:size(bbox,1)
                    J = imcrop(img,bbox(i,:));
                end
            else
                J = img;
            end
        end
        
        function [label,scores] = predicts(app, img)
            img = imresize(img,[227 227]);
            [label,scores] = classify(app.net, img);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.net = load("model\newnet.mat");
            app.net = app.net.newnet;
            app.faceDetector = mtcnn.Detector('UseGPU',true);
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            [baseName, folder] = uigetfile();
            img_path = fullfile(folder, baseName);
            app.img = imread(img_path);
            app.ImageLabel.Text = join(['Image: ', img_path]);
            imshow(app.img, 'Parent', app.UIAxes);
            drawnow;
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            if ~isempty(app.img)
                [cropped_img,bbox] = app.cropface(app.img);
                bboxPoints = bbox2points(bbox(1, :));
                bboxPolygon = reshape(bboxPoints', 1, []);
                app.img = insertShape(app.img,"Polygon",bboxPolygon);
                [label,scores] = app.predicts(cropped_img);
                app.img = insertText(app.img,bboxPoints(1,:),string(label));
                imshow(app.img, 'Parent', app.UIAxes);
                drawnow;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = {'Select Image'; ''};
            app.DetectButton = uibutton(app.UIFigure, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end